classdef ui_draft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PatientIDTab                    matlab.ui.container.Tab
        SearchExistingPatientButton     matlab.ui.control.Button
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        ECOGLocationTab                 matlab.ui.container.Tab
        SubplotNumberEditField          matlab.ui.control.NumericEditField
        SubplotNumberEditFieldLabel     matlab.ui.control.Label
        SubplotEndEditField             matlab.ui.control.NumericEditField
        SubplotEndEditFieldLabel        matlab.ui.control.Label
        SubplotStartEditField           matlab.ui.control.NumericEditField
        SubplotStartEditFieldLabel      matlab.ui.control.Label
        SubplotColumnEditField          matlab.ui.control.NumericEditField
        SubplotColumnEditFieldLabel     matlab.ui.control.Label
        SubplotRowEditField             matlab.ui.control.NumericEditField
        SubplotRowEditFieldLabel        matlab.ui.control.Label
        ColorbarLocationTab             matlab.ui.container.Tab
        SubplotEndEditField_2           matlab.ui.control.NumericEditField
        SubplotEndEditField_2Label      matlab.ui.control.Label
        SubplotNumberEditField_2        matlab.ui.control.NumericEditField
        SubplotNumberEditField_2Label   matlab.ui.control.Label
        SubplotStartEditField_2         matlab.ui.control.NumericEditField
        SubplotStartEditField_2Label    matlab.ui.control.Label
        SubplotColumnEditField_2        matlab.ui.control.NumericEditField
        SubplotColumnEditField_2Label   matlab.ui.control.Label
        SubplotRowEditField_2           matlab.ui.control.NumericEditField
        SubplotRowEditField_2Label      matlab.ui.control.Label
        AddElectrodesTab                matlab.ui.container.Tab
        AddElectrodeButton              matlab.ui.control.Button
        SurfacesEditField_2Label_2      matlab.ui.control.Label
        DepthsEditField                 matlab.ui.control.EditField
        DepthsEditFieldLabel            matlab.ui.control.Label
        DepthFocusEditField             matlab.ui.control.EditField
        DepthFocusEditFieldLabel        matlab.ui.control.Label
        DepthColorEditField             matlab.ui.control.EditField
        DepthColorEditFieldLabel        matlab.ui.control.Label
        FirstElectrodeEditField         matlab.ui.control.NumericEditField
        FirstElectrodeEditFieldLabel    matlab.ui.control.Label
        LastElectrodeEditField          matlab.ui.control.NumericEditField
        LastElectrodeEditFieldLabel     matlab.ui.control.Label
        DepthLabelEditField             matlab.ui.control.EditField
        DepthLabelEditFieldLabel        matlab.ui.control.Label
        ShowPlanesEditField             matlab.ui.control.NumericEditField
        ShowPlanesEditFieldLabel        matlab.ui.control.Label
        ViewAngleEditField              matlab.ui.control.EditField
        ViewAngleEditFieldLabel         matlab.ui.control.Label
        SurfacesOpacityEditField        matlab.ui.control.EditField
        SurfacesEditField               matlab.ui.control.EditField
        SurfacesEditField_2Label        matlab.ui.control.Label
        SubplotEndEditField_3           matlab.ui.control.NumericEditField
        SubplotEndEditField_3Label      matlab.ui.control.Label
        SubplotNumberEditField_3        matlab.ui.control.NumericEditField
        SubplotNumberEditField_3Label   matlab.ui.control.Label
        SubplotColumnEditField_3        matlab.ui.control.NumericEditField
        SubplotColumnEditField_3Label   matlab.ui.control.Label
        SubplotRowEditField_3           matlab.ui.control.NumericEditField
        SubplotRowEditField_3Label      matlab.ui.control.Label
        SubplotStartEditField_2Label_2  matlab.ui.control.Label
        SubplotStartEditField_4         matlab.ui.control.NumericEditField
        AddElectrodeDropDown            matlab.ui.control.DropDown
        AddElectrodeDropDownLabel       matlab.ui.control.Label
        RemoveElectrodesTab             matlab.ui.container.Tab
        RemoveSelectedElectrodesButton  matlab.ui.control.Button
        ConfiguredElectrodesLabel       matlab.ui.control.Label
        SeizureDataTab                  matlab.ui.container.Tab
    end

    
    properties (Access = public)
        NumElectrodes                   double = 0;
        ElectrodeCheckBoxArray          matlab.ui.control.CheckBox             
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AddElectrodeDropDown
        function AddElectrodeDropDownValueChanged(app, event)
            value = app.AddElectrodeDropDown.Value;
            if value == "Surface"
                app.SurfacesEditField.Editable = "on";
                app.SurfacesEditField.Enable = "on";
                app.ViewAngleEditField.Editable = "on";
                app.ViewAngleEditField.Enable = "on";
                app.SurfacesOpacityEditField.Editable = "on";
                app.SurfacesOpacityEditField.Enable = "on";
                app.ShowPlanesEditField.Editable = "on";
                app.ShowPlanesEditField.Enable = "on";

                app.DepthLabelEditField.Editable = "off";
                app.DepthLabelEditField.Enable = "off";
                app.FirstElectrodeEditField.Editable = "off";
                app.FirstElectrodeEditField.Enable = "off";
                app.LastElectrodeEditField.Editable = "off";
                app.LastElectrodeEditField.Enable = "off";
                app.DepthsEditField.Editable = "off";
                app.DepthsEditField.Enable = "off";
                app.DepthColorEditField.Editable = "off";
                app.DepthColorEditField.Enable = "off";
                app.DepthFocusEditField.Editable = "off";
                app.DepthFocusEditField.Enable = "off";
            elseif value == "Depth"
                app.DepthLabelEditField.Editable = "on";
                app.DepthLabelEditField.Enable = "on";
                app.FirstElectrodeEditField.Editable = "on";
                app.FirstElectrodeEditField.Enable = "on";
                app.LastElectrodeEditField.Editable = "on";
                app.LastElectrodeEditField.Enable = "on";
                app.DepthsEditField.Editable = "on";
                app.DepthsEditField.Enable = "on";
                app.DepthColorEditField.Editable = "on";
                app.DepthColorEditField.Enable = "on";
                app.DepthFocusEditField.Editable = "on";
                app.DepthFocusEditField.Enable = "on";

                app.SurfacesEditField.Editable = "off";
                app.SurfacesEditField.Enable = "off";
                app.ViewAngleEditField.Editable = "off";
                app.ViewAngleEditField.Enable = "off";
                app.SurfacesOpacityEditField.Editable = "off";
                app.SurfacesOpacityEditField.Enable = "off";
                app.ShowPlanesEditField.Editable = "off";
                app.ShowPlanesEditField.Enable = "off";
            end
        end

        % Button pushed function: AddElectrodeButton
        function AddElectrodeButtonPushed(app, event)
            app.NumElectrodes = app.NumElectrodes + 1;
            newCheckBox = uicheckbox(app.RemoveElectrodesTab);
            newCheckBox.Text = [app.AddElectrodeDropDown.Value ' Electrode ' num2str(app.NumElectrodes)];
            newCheckBox.Position = [
                app.ConfiguredElectrodesLabel.Position(1), ... 
                app.ConfiguredElectrodesLabel.Position(2) - 30 * app.NumElectrodes, ...
                300, ...
                25
            ];

            app.ElectrodeCheckBoxArray(app.NumElectrodes) = newCheckBox;
        end

        % Button pushed function: RemoveSelectedElectrodesButton
        function RemoveSelectedElectrodesButtonPushed(app, event)
            newElectrodeCheckBoxArray = [];
            j = 1;
            for i = 1:length(app.ElectrodeCheckBoxArray)
                if app.ElectrodeCheckBoxArray(i).Value
                    delete(app.ElectrodeCheckBoxArray(i));
                else
                    newElectrodeCheckBoxArray = [newElectrodeCheckBoxArray; app.ElectrodeCheckBoxArray(i)];
                end
            end

            app.ElectrodeCheckBoxArray = newElectrodeCheckBoxArray;
            app.NumElectrodes = length(newElectrodeCheckBoxArray);
            for i=1:length(app.ElectrodeCheckBoxArray)
                app.ElectrodeCheckBoxArray(i).Position(2) = app.ConfiguredElectrodesLabel.Position(2) - 30 * i;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PatientIDTab = uitab(app.TabGroup);
            app.PatientIDTab.Title = 'Patient ID';
            app.PatientIDEditFieldLabel = uilabel(app.PatientIDTab);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.PatientIDTab, 'text');
            app.SearchExistingPatientButton = uibutton(app.PatientIDTab, 'push');
            app.SearchExistingPatientButton.Text = 'Search Existing Patient';
            app.ECOGLocationTab = uitab(app.TabGroup);
            app.ECOGLocationTab.Title = 'ECOG Location';
            app.SubplotRowEditFieldLabel = uilabel(app.ECOGLocationTab);
            app.SubplotRowEditFieldLabel.Text = 'Subplot Row';
            app.SubplotRowEditField = uieditfield(app.ECOGLocationTab, 'numeric');
            app.SubplotColumnEditFieldLabel = uilabel(app.ECOGLocationTab);
            app.SubplotColumnEditFieldLabel.Text = 'Subplot Column';
            app.SubplotColumnEditField = uieditfield(app.ECOGLocationTab, 'numeric');
            app.SubplotStartEditFieldLabel = uilabel(app.ECOGLocationTab);
            app.SubplotStartEditFieldLabel.Text = 'Subplot Start';
            app.SubplotStartEditField = uieditfield(app.ECOGLocationTab, 'numeric');
            app.SubplotEndEditFieldLabel = uilabel(app.ECOGLocationTab);
            app.SubplotEndEditFieldLabel.Text = 'Subplot End';
            app.SubplotEndEditField = uieditfield(app.ECOGLocationTab, 'numeric');
            app.SubplotNumberEditFieldLabel = uilabel(app.ECOGLocationTab);
            app.SubplotNumberEditFieldLabel.Text = 'Subplot Number';
            app.SubplotNumberEditField = uieditfield(app.ECOGLocationTab, 'numeric');
            app.ColorbarLocationTab = uitab(app.TabGroup);
            app.ColorbarLocationTab.Title = 'Colorbar Location';
            app.SubplotRowEditField_2Label = uilabel(app.ColorbarLocationTab);
            app.SubplotRowEditField_2Label.Text = 'Subplot Row';
            app.SubplotRowEditField_2 = uieditfield(app.ColorbarLocationTab, 'numeric');
            app.SubplotColumnEditField_2Label = uilabel(app.ColorbarLocationTab);
            app.SubplotColumnEditField_2Label.Text = 'Subplot Column';
            app.SubplotColumnEditField_2 = uieditfield(app.ColorbarLocationTab, 'numeric');
            app.SubplotStartEditField_2Label = uilabel(app.ColorbarLocationTab);
            app.SubplotStartEditField_2Label.Text = 'Subplot Start';
            app.SubplotStartEditField_2 = uieditfield(app.ColorbarLocationTab, 'numeric');
            app.SubplotNumberEditField_2Label = uilabel(app.ColorbarLocationTab);
            app.SubplotNumberEditField_2Label.Text = 'Subplot Number';
            app.SubplotNumberEditField_2 = uieditfield(app.ColorbarLocationTab, 'numeric');
            app.SubplotEndEditField_2Label = uilabel(app.ColorbarLocationTab);
            app.SubplotEndEditField_2Label.Text = 'Subplot End';
            app.SubplotEndEditField_2 = uieditfield(app.ColorbarLocationTab, 'numeric');
            app.AddElectrodesTab = uitab(app.TabGroup);
            app.AddElectrodesTab.Title = 'Add Electrodes';
            app.AddElectrodeDropDownLabel = uilabel(app.AddElectrodesTab);
            app.AddElectrodeDropDownLabel.Text = 'Add Electrode';
            app.AddElectrodeDropDown = uidropdown(app.AddElectrodesTab);
            app.AddElectrodeDropDown.Items = {'Surface', 'Depth'};
            app.AddElectrodeDropDown.ValueChangedFcn = createCallbackFcn(app, @AddElectrodeDropDownValueChanged, true);
            app.AddElectrodeDropDown.Value = 'Surface';
            app.SubplotStartEditField_4 = uieditfield(app.AddElectrodesTab, 'numeric');
            app.SubplotStartEditField_2Label_2 = uilabel(app.AddElectrodesTab);
            app.SubplotStartEditField_2Label_2.Text = 'Subplot Start';
            app.SubplotRowEditField_3Label = uilabel(app.AddElectrodesTab);
            app.SubplotRowEditField_3Label.Text = 'Subplot Row';
            app.SubplotRowEditField_3 = uieditfield(app.AddElectrodesTab, 'numeric');
            app.SubplotColumnEditField_3Label = uilabel(app.AddElectrodesTab);
            app.SubplotColumnEditField_3Label.Text = 'Subplot Column';
            app.SubplotColumnEditField_3 = uieditfield(app.AddElectrodesTab, 'numeric');
            app.SubplotNumberEditField_3Label = uilabel(app.AddElectrodesTab);
            app.SubplotNumberEditField_3Label.Text = 'Subplot Number';
            app.SubplotNumberEditField_3 = uieditfield(app.AddElectrodesTab, 'numeric');
            app.SubplotEndEditField_3Label = uilabel(app.AddElectrodesTab);
            app.SubplotEndEditField_3Label.Text = 'Subplot End';
            app.SubplotEndEditField_3 = uieditfield(app.AddElectrodesTab, 'numeric');
            app.SurfacesEditField_2Label = uilabel(app.AddElectrodesTab);
            app.SurfacesEditField_2Label.Text = 'Surfaces';
            app.SurfacesEditField = uieditfield(app.AddElectrodesTab, 'text');
            app.SurfacesOpacityEditField = uieditfield(app.AddElectrodesTab, 'text');
            app.ViewAngleEditFieldLabel = uilabel(app.AddElectrodesTab);
            app.ViewAngleEditFieldLabel.Text = 'View Angle';
            app.ViewAngleEditField = uieditfield(app.AddElectrodesTab, 'text');
            app.ShowPlanesEditFieldLabel = uilabel(app.AddElectrodesTab);
            app.ShowPlanesEditFieldLabel.Text = 'Show Planes';
            app.ShowPlanesEditField = uieditfield(app.AddElectrodesTab, 'numeric');
            app.DepthLabelEditFieldLabel = uilabel(app.AddElectrodesTab);
            app.DepthLabelEditFieldLabel.Text = 'Depth Label';
            app.DepthLabelEditField = uieditfield(app.AddElectrodesTab, 'text');
            app.DepthLabelEditField.Editable = 'off';
            app.DepthLabelEditField.Enable = 'off';
            app.LastElectrodeEditFieldLabel = uilabel(app.AddElectrodesTab);
            app.LastElectrodeEditFieldLabel.Text = 'Last Electrode';
            app.LastElectrodeEditField = uieditfield(app.AddElectrodesTab, 'numeric');
            app.LastElectrodeEditField.Editable = 'off';
            app.LastElectrodeEditField.Enable = 'off';
            app.FirstElectrodeEditFieldLabel = uilabel(app.AddElectrodesTab);
            app.FirstElectrodeEditFieldLabel.Text = 'First Electrode';
            app.FirstElectrodeEditField = uieditfield(app.AddElectrodesTab, 'numeric');
            app.FirstElectrodeEditField.Editable = 'off';
            app.FirstElectrodeEditField.Enable = 'off';
            app.DepthColorEditFieldLabel = uilabel(app.AddElectrodesTab);
            app.DepthColorEditFieldLabel.Text = 'Depth Color';
            app.DepthColorEditField = uieditfield(app.AddElectrodesTab, 'text');
            app.DepthColorEditField.Editable = 'off';
            app.DepthColorEditField.Enable = 'off';
            app.DepthFocusEditFieldLabel = uilabel(app.AddElectrodesTab);
            app.DepthFocusEditFieldLabel.Text = 'Depth Focus';
            app.DepthFocusEditField = uieditfield(app.AddElectrodesTab, 'text');
            app.DepthFocusEditField.Editable = 'off';
            app.DepthFocusEditField.Enable = 'off';
            app.DepthsEditFieldLabel = uilabel(app.AddElectrodesTab);
            app.DepthsEditFieldLabel.Text = 'Depths';
            app.DepthsEditField = uieditfield(app.AddElectrodesTab, 'text');
            app.DepthsEditField.Editable = 'off';
            app.DepthsEditField.Enable = 'off';
            app.SurfacesEditField_2Label_2 = uilabel(app.AddElectrodesTab);
            app.SurfacesEditField_2Label_2.Text = 'Surfaces Opacity';
            app.AddElectrodeButton = uibutton(app.AddElectrodesTab, 'push');
            app.AddElectrodeButton.ButtonPushedFcn = createCallbackFcn(app, @AddElectrodeButtonPushed, true);
            app.AddElectrodeButton.Text = 'Add Electrode';
            app.RemoveElectrodesTab = uitab(app.TabGroup);
            app.RemoveElectrodesTab.Title = 'Remove Electrodes';
            app.ConfiguredElectrodesLabel = uilabel(app.RemoveElectrodesTab);
            app.ConfiguredElectrodesLabel.Text = 'Configured Electrodes';
            app.RemoveSelectedElectrodesButton = uibutton(app.RemoveElectrodesTab, 'push');
            app.RemoveSelectedElectrodesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedElectrodesButtonPushed, true);
            app.RemoveSelectedElectrodesButton.Text = 'Remove Selected Electrodes';
            app.SeizureDataTab = uitab(app.TabGroup);
            app.SeizureDataTab.Title = 'Seizure Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_draft

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end