classdef ui_draft_updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SubplotConfigurationPanel       matlab.ui.container.Panel
        RepopulateDepthsButton          matlab.ui.control.Button
        FindSimilarSubjectsButton       matlab.ui.control.Button
        LoadTemplateLayoutButton        matlab.ui.control.Button
        LoadTemplateLayoutEditField     matlab.ui.control.EditField
        LoadTemplateLayoutLabel         matlab.ui.control.Label
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        PreviewLayoutButton             matlab.ui.control.Button
        ResetSubplotConfigurationButton  matlab.ui.control.Button
        ConfigurePlotLayoutEditField    matlab.ui.control.EditField
        ConfigurePlotLayoutLabel        matlab.ui.control.Label
        SaveSubplotConfigurationButton  matlab.ui.control.Button
        UpdateDepthElectrodesButton     matlab.ui.control.Button
        UpdateSurfaceElectrodesButton   matlab.ui.control.Button
        AddDepthElectrodePlotButton     matlab.ui.control.Button
        AddSurfaceElectrodePlotButton   matlab.ui.control.Button
        DepthElectrodesLabel            matlab.ui.control.Label
        DepthElectrodesTable            matlab.ui.control.Table
        SurfaceElectrodesLabel          matlab.ui.control.Label
        SurfaceElectrodesTable          matlab.ui.control.Table
        ConfigurePlotElementsLabel      matlab.ui.control.Label
        ConfigurePlotElementsTable      matlab.ui.control.Table
        SearchExistingPatientButton     matlab.ui.control.Button
        VideoParametersPanel            matlab.ui.container.Panel
        ResetAllButton                  matlab.ui.control.Button
        RunOPSCEAButton                 matlab.ui.control.Button
        JumpToEditFieldLabel            matlab.ui.control.Label
        JumpToEditField                 matlab.ui.control.EditField
        LoadDataButton                  matlab.ui.control.Button
        DataLoadedInfoLabel             matlab.ui.control.Label
        ResetVideoParametersSeizureDataButton  matlab.ui.control.Button
        GenerateVideoPreviewButton      matlab.ui.control.Button
        SaveClipParams                  matlab.ui.control.Button
        ClipIDEditField                 matlab.ui.control.EditField
        ClipIDEditFieldLabel            matlab.ui.control.Label
        SearchExistingClipButton        matlab.ui.control.Button
        ClipIDConfigTable               matlab.ui.control.Table
    end

    properties (Access = public)
        % Initialize empty parameters that will be populated when
        % LoadDataButton is pressed
        d = [];
        LL = [];
        sfx = 0;
        ntp = 0;
        scl = 1;
        ts = [];
        jumpto = 1;
        nns = [];
        currentLoadedClip = '';

        clipParamRowNames = [
            "vidstart" 
            "vidstop" 
            "blstart" 
            "blstop"
            "llw" 
            "iceeg_scale" 
            "fps" 
            "cax" 
            "gsp"
            "cm" 
            "iceegwin" 
            "marg" 
            "slicebright" 
            "etype"
        ];

        clipParamsDefaults = struct( ...
            'vidstart', 0, ...
            'vidstop', 0, ...
            'blstart', 0, ...
            'blstop', 0, ...
            'llw', 0.25, ...
            'iceeg_scale', 97, ...
            'fps', 5, ...
            'cax', [-20 20], ...
            'gsp', 25, ...
            'cm', 'cmOPSCEAjet', ...
            'iceegwin', 5, ...
            'marg', 1, ...
            'slicebright', 25, ...
            'etype', 'clinical' ...
        );
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize plot layout
            app.ConfigurePlotLayoutEditField.Value = "3,100";
            
            % Initialize ECoG & colorbar configuration
            elementNames = {"ECoG"; "Colorbar"};
            rows = [6; 1];
            cols = [20; 5];
            tile = [81; 75];
            app.ConfigurePlotElementsTable.Data = table(elementNames, tile, rows, cols);
            app.ConfigurePlotElementsTable.Data.Properties.RowNames = ["ecog" "cb"];

            % Set empty table
            rows = [];
            cols = [];
            tile = [];
            zoom = [];
            surfaces = [];
            opacity = [];
            view = [];
            show = false(0, 0);
            remove = false(0, 0);
            app.SurfaceElectrodesTable.Data = table(tile, rows, cols, zoom, surfaces, opacity, view, show, remove);

            rows = [];
            cols = [];
            tile = [];
            zoom = [];
            labels = [];
            color = [];
            depths = [];
            remove = false(0, 0);
            app.DepthElectrodesTable.Data = table(tile, rows, cols, zoom, labels, color, depths, remove);

            % Initialize table for video clip configuration
            paramNames = {
                "Video Start"; 
                "Video Stop";
                "Baseline Start"; 
                "Baseline Stop";
                "Line Length Window (sec)"; 
                "ICEEG Scale";
                "FPS"; 
                "Color Axis";
                "GSP"; 
                "Color Map";
                "ICEEG Window"; 
                "Margin (sec)";
                "Slice Brightness"; 
                "Electrode Type"
            };

            defaultValues = {
                app.clipParamsDefaults.vidstart;
                app.clipParamsDefaults.vidstop;
                app.clipParamsDefaults.blstart; 
                app.clipParamsDefaults.blstop;
                app.clipParamsDefaults.llw; 
                app.clipParamsDefaults.iceeg_scale; 
                app.clipParamsDefaults.fps; 
                convertCharsToStrings(strjoin(arrayfun(@num2str, app.clipParamsDefaults.cax, 'UniformOutput', false), ","));
                app.clipParamsDefaults.gsp; 
                app.clipParamsDefaults.cm;
                app.clipParamsDefaults.iceegwin; 
                app.clipParamsDefaults.marg;
                app.clipParamsDefaults.slicebright; 
                app.clipParamsDefaults.etype;
            };
            app.ClipIDConfigTable.Data = table(paramNames, defaultValues);
            app.ClipIDConfigTable.Data.Properties.RowNames = app.clipParamRowNames;

            % Fix alignment in tables
            % TODO(steph): is there a better way to do this?
            s_subplot = uistyle("HorizontalAlignment", "left");
            addStyle(app.ConfigurePlotElementsTable, s_subplot, "table", "");
            addStyle(app.SurfaceElectrodesTable, s_subplot, "table", "");
            addStyle(app.DepthElectrodesTable, s_subplot, "table", "");

            s_clip = uistyle("HorizontalAlignment", "left");
            addStyle(app.ClipIDConfigTable, s_clip, "table", "");
        end

        % Callback function
        function AddElectrodeDropDownValueChanged(app, event)

        end

        % Callback function
        function AddElectrodeButtonPushed(app, event)

        end

        % Callback function
        function RemoveSelectedElectrodesButtonPushed(app, event)
            
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            % app.TabGroup.Position(1) = 1;
            % app.TabGroup.Position(2) = 1;
            % app.TabGroup.Position(4) = position(4);
        end

        % Button pushed function: SearchExistingPatientButton
        function SearchExistingPatientButtonPushed(app, event)
            templateID = app.LoadTemplateLayoutEditField.Value;
            if ~isempty(templateID)
                message = sprintf("Are you sure you want to overwrite current subplot configuration?");
                btn1 = "Yes";
                btn2 = "No";
                choice = questdlg(message, "Warning", btn1, btn2, btn2);
                if choice == "No"
                    return;
                end
            end
            
            % Reset template if it was previously set
            app.LoadTemplateLayoutEditField.Value = "";

            patientID = app.PatientIDEditField.Value;
            filename = fullfile(getenv("KLEEN_DATA"), 'opscea', patientID, 'patient_params.mat');
            if exist(filename, "file") == 2
                patientParams = load(filename);

                fill_subplot_configuration(patientParams, app);
            else
                msgbox(sprintf("Subplot params for patient %s not found. Please create a new configuration.", patientID));
            end
        end

        % Button pushed function: AddSurfaceElectrodePlotButton
        function AddSurfaceElectrodePlotButtonPushed(app, event)
            newRow = cell2table({0, 0, 0, 0, '', '', '', false, false});
            newRow.Properties.VariableNames = [
                "tile", "rows", "cols", "zoom", "surfaces", "opacity", "view", "show", "remove"
            ];
            app.SurfaceElectrodesTable.Data = [app.SurfaceElectrodesTable.Data; newRow];
        end

        % Button pushed function: UpdateDepthElectrodesButton
        function UpdateDepthElectrodesButtonPushed(app, event)
            [numElectrodes, numColumns] = size(app.DepthElectrodesTable.Data);
            electrodesToRemove = [];
            for i=1:numElectrodes
                % RemoveElectrode column is always the last column
                if app.DepthElectrodesTable.Data{i, numColumns} == true
                    electrodesToRemove = [electrodesToRemove; i];
                end
            end
            
            app.DepthElectrodesTable.Data(electrodesToRemove, :) = [];
        end

        % Button pushed function: AddDepthElectrodePlotButton
        function AddDepthElectrodePlotButtonPushed(app, event)
            newRow = cell2table({0, 0, 0, 0, '', '', '', false(1, 1)});
            newRow.Properties.VariableNames = [
                "tile", "rows", "cols", "zoom", "labels", "color", "depths", "remove"
            ];
            app.DepthElectrodesTable.Data = [app.DepthElectrodesTable.Data; newRow];
        end

        % Button pushed function: UpdateSurfaceElectrodesButton
        function UpdateSurfaceElectrodesButtonPushed(app, event)
            [numElectrodes, numColumns] = size(app.SurfaceElectrodesTable.Data);
            electrodesToRemove = [];
            for i=1:numElectrodes
                % RemoveElectrode column is always the last column
                if app.SurfaceElectrodesTable.Data{i, numColumns} == true
                    electrodesToRemove = [electrodesToRemove; i];
                end
            end
            
            app.SurfaceElectrodesTable.Data(electrodesToRemove, :) = [];
        end

        % Button pushed function: SearchExistingClipButton
        function SearchExistingClipButtonPushed(app, event)
            patientID = app.PatientIDEditField.Value;
            
            % Check that patientID is not empty
            if strrep(patientID, " ", "") == ""
                msgbox(sprintf("Please specify a patient ID"));
                return;
            end

            % Check that patient ID is valid (e.g. a folder has been
            % created for this patient). TODO(steph): is this needed? 
            % Should we always guarantee that valid patients will have
            % an existing folder (that may or may not be empty?)
            patientFolder = fullfile(getenv("KLEEN_DATA"), 'opscea', patientID);
            if exist(patientFolder, "dir") ~= 7
                msgbox(sprintf("Patient %s not found. Please specify another patient or create a new subplot configuration for this patient.", patientID));
                return;
            end
            
            clipID = app.ClipIDEditField.Value;
            filename = fullfile(patientFolder, [char(patientID) '_' char(clipID)], [char(patientID) '_' char(clipID) '_params.mat']);
            % filename = fullfile(patientFolder, [char(patientID) '_' char(clipID)], 'clip_params.mat');

            if exist(filename, "file") == 2
                clipParams = load(filename);
                paramNames = {
                    "Video Start"; 
                    "Video Stop";
                    "Baseline Start"; 
                    "Baseline Stop";
                    "Line Length Window (sec)"; 
                    "ICEEG Scale";
                    "FPS"; 
                    "Color Axis";
                    "GSP"; 
                    "Color Map";
                    "ICEEG Window"; 
                    "Margin (sec)";
                    "Slice Brightness"; 
                    "Electrode Type"
                };

                % Check that certain parameters exist
                if ~isfield(clipParams, 'vidstart') || ~isfield(clipParams, 'vidstop') || ~isfield(clipParams, 'blstart') || ~isfield(clipParams, 'blstop')
                    % Display a message box if 'vidstart' is missing
                    msgbox("Missing one or more of the following fields in the clip configuration: vidstart, vidstop, blstart, blstart." + newline + newline + "Please use EEGHILITE to save these parameters.");
                    return;
                end

                loadedValues = {
                    % These parameters should have been set using EEGHILITE
                    clipParams.vidstart; 
                    clipParams.vidstop;
                    clipParams.blstart; 
                    clipParams.blstop;
                    % These values may or may not be set
                    getFieldOrDefault(clipParams, 'llw', app.clipParamsDefaults.llw); 
                    getFieldOrDefault(clipParams, 'iceeg_scale', app.clipParamsDefaults.iceeg_scale);
                    getFieldOrDefault(clipParams, 'fps', app.clipParamsDefaults.fps); 
                    convertCharsToStrings(strjoin(arrayfun(@num2str, getFieldOrDefault(clipParams, 'cax', app.clipParamsDefaults.cax), 'UniformOutput', false), ","));
                    getFieldOrDefault(clipParams, 'gsp', app.clipParamsDefaults.gsp); 
                    getFieldOrDefault(clipParams, 'cm', app.clipParamsDefaults.cm);
                    getFieldOrDefault(clipParams, 'iceegwin', app.clipParamsDefaults.iceegwin); 
                    getFieldOrDefault(clipParams, 'marg', app.clipParamsDefaults.marg);
                    getFieldOrDefault(clipParams, 'slicebright', app.clipParamsDefaults.slicebright); 
                    getFieldOrDefault(clipParams, 'etype', app.clipParamsDefaults.etype)
                };

                app.ClipIDConfigTable.Data = table(paramNames, loadedValues);
                app.ClipIDConfigTable.Data.Properties.RowNames = app.clipParamRowNames;
            else
                msgbox(sprintf("Clip %s for patient %s not found. Please create a new configuration.", clipID, patientID));
            end
        end

        % Button pushed function: SaveSubplotConfigurationButton
        function SaveSubplotConfigurationButtonPushed(app, event)
            patientID = app.PatientIDEditField.Value;

            % Make sure patientID is set
            if strrep(patientID, " ", "") == ""
                msgbox(sprintf("Please specify a patient ID to save this configuration."));
                return;
            end
            
            % Validate patientID
            folderPath = fullfile(getenv("KLEEN_DATA"), 'opscea', patientID);
            if exist(folderPath, "dir") ~= 7
                mkdir(folderPath)
            end

            % Patient params: layout
            splitStr = split(app.ConfigurePlotLayoutEditField.Value, ",");
            layout.rows = str2double(splitStr(1));
            layout.cols = str2double(splitStr(2));

            % Patient params: ecog
            ecogFields = app.ConfigurePlotElementsTable.Data{"ecog", 2:4};
            rows = double(ecogFields(2));
            cols = double(ecogFields(3));
            tile = double(ecogFields(1));
            ecog = table(rows, cols, tile);

            % Patient params: cb 
            cbFields = app.ConfigurePlotElementsTable.Data{"cb", 2:4};
            rows = double(cbFields(2));
            cols = double(cbFields(3));
            tile = double(cbFields(1));
            cb = table(rows, cols, tile);

            % Patient params: surface electrodes
            surfaceElectrodesTable = app.SurfaceElectrodesTable.Data;
            tile = double(surfaceElectrodesTable.tile);
            rows = double(surfaceElectrodesTable.rows);
            cols = double(surfaceElectrodesTable.cols);
            zoom = double(surfaceElectrodesTable.zoom);
            surfaces = surfaceElectrodesTable.surfaces;
            view = surfaceElectrodesTable.view;
            show = surfaceElectrodesTable.show;

            % Need to convert comma-delimited string to array
            [numRows, ~] = size(app.SurfaceElectrodesTable.Data);
            opacity = cell(numRows, 1);
            for i=1:numRows
                opacity{i, 1} = transpose(double(split(surfaceElectrodesTable.opacity(i), ",")));
            end
            
            surface = table(zoom, surfaces, opacity, view, show, rows, cols, tile);

            % Patient params: depth electrodes
            depthElectrodesTable = app.DepthElectrodesTable.Data;
            tile = double(depthElectrodesTable.tile);
            rows = double(depthElectrodesTable.rows);
            cols = double(depthElectrodesTable.cols);
            zoom = double(depthElectrodesTable.zoom);
            labels = depthElectrodesTable.labels;

            % Need to convert comma-delimited string to array
            [numRows, ~] = size(app.DepthElectrodesTable.Data);
            depths = cell(numRows, 1);
            color = cell(numRows, 3);
            for i=1:numRows
                % DEPRECATED: assumes depthElectrodesTable.depths(i) is a
                % comma-delimited list start,start+1,...,end
                % depths{i, 1} = transpose(double(split(depthElectrodesTable.depths(i), ",")));

                % Assumes depthElectrodesTable.depths(i) has the format start:end
                depths{i, 1} = eval(depthElectrodesTable.depths(i));
                color(i, :) = num2cell(double(split(depthElectrodesTable.color(i), ",")));
            end
            color = double(string(color));

            depth = table(zoom, labels, color, depths, rows, cols, tile);

            % Save data
            filename = sprintf("%s/patient_params.mat", folderPath);
            save(filename, 'layout', 'ecog', 'cb', 'surface', 'depth');
            
            msgbox(sprintf("Saved configuration for patient %s", patientID));
        end

        % Button pushed function: SaveClipParams
        function SaveClipParamsButtonPushed(app, event)
            patientID = app.PatientIDEditField.Value;

            finalClipID = save_clip_params(app);
            
            if ~isempty(finalClipID)
                message = sprintf("Video params for clip ID %s saved for patient %s", finalClipID, patientID);
                msgbox(message);
            end
        end

        % Button pushed function: GenerateVideoPreviewButton
        function GenerateVideoPreviewButtonButtonPushed(app, event)
            pt = app.PatientIDEditField.Value;
            sz = app.ClipIDEditField.Value;
            ptsz = [pt '_' sz]; % prefix for filenames of specific seizure
        
            % Check if the seizure data that has been loaded matches
            % this patient and clip
            if ~strcmp(app.currentLoadedClip, ptsz) || isempty(app.d)
               msgbox(sprintf("Data for patient %s with clip ID %s has not yet been loaded. Skipping preview generation.", pt, sz)); 
               return
            end

            datapath = getenv('KLEEN_DATA');
            opsceapath = fullfile(datapath, 'opscea'); %path for parameters sheet
            imagingpath = fullfile(datapath, 'imaging');
            
            ptpath = fullfile(opsceapath, pt); % patient's folder
            ptparams = fullfile(ptpath, 'patient_params.mat');
            % At this point, patient_params.mat should have been generated,
            % so params should be read from the saved file
            if exist(ptparams, 'file') ~= 2
                msgbox(sprintf("patient_params.mat does not exist for patient %s. Please use 'Subplot Configuration' to save out these parameters.", pt));
                return
            end

            load_patient_params(ptparams);
            load_clip_params('', false, app);

            loadingbox = uiprogressdlg(app.UIFigure, 'Title', 'Loading video preview...', 'Indeterminate', 'on');

            % bgc = app.GenerateVideoPreviewButton.BackgroundColor;
            % tc = app.GenerateVideoPreviewButton.FontColor;
            % app.GenerateVideoPreviewButton.BackgroundColor = [0.8 0.8 0.8];
            % app.GenerateVideoPreviewButton.Text = 'Loading...';
            % app.GenerateVideoPreviewButton.FontColor = [0.5 0.5 0.5];
        
            [eleclabels, em, ~, depthch, axislim] = load_prepare_imaging(pt, imagingpath);
            [ytl, nch, chanorder] = prepare_plotting(eleclabels, app.nns, app.d, true);
            
            global S;
            S.maxbased = true;
            app.jumpto = str2double(app.JumpToEditField.Value) * app.sfx + 1;
            % Make sure that jumpto is not greater than the lenth of
            % the video
            if app.jumpto > app.ntp-app.sfx*S.iceegwin
                message = sprintf("Value %s exceeds length of video timeframe. Setting preview to first second of video.", app.JumpToEditField.Value);
                msgbox(message);
                app.jumpto = app.sfx + 1;
            end
            
            frametimpoints = app.jumpto:S.fram:app.ntp-app.sfx*S.iceegwin;
            i  = frametimpoints(1);

            % 9/14/2025- I added these parts because it wasn't updating after changing the value in the UI. Seems like we should always have it pull from the UI fields like this for all variables, a future change to tackle.-JK
            prctl=double(app.ClipIDConfigTable.Data{"iceeg_scale", 2}{1});
            app.scl = 2/diff(prctile(reshape(app.d,1,numel(app.d)),[100-prctl prctl]));
            S.etype=app.ClipIDConfigTable.Data{"etype", 2}; 
            S.etype=S.etype{1};
            
            figure('color', 'w', 'Position', [1 5 1280 700], 'Name', 'Preview', 'NumberTitle', 'off');
            %figure('color', 'w', 'Position', [1 5 1511 861], 'Name', 'Preview', 'NumberTitle', 'off');
            global tiles;
            tiledlayout(tiles.layout.rows, tiles.layout.cols);
            plot_frame( ...
                i, true, app.LL, app.d, ...
                app.sfx, nch, app.nns, app.scl, app.ts, ...
                ytl, chanorder, true, pt, em, depthch, ...
                axislim, datapath, S.maxbased ...
            );

            % app.GenerateVideoPreviewButton.BackgroundColor = bgc;
            % app.GenerateVideoPreviewButton.Text = 'Preview';
            % app.GenerateVideoPreviewButton.FontColor = tc;
            
            % Give an extra second before closing the box
            pause('on');
            pause(2);
            pause('off');
            close(loadingbox);
            
        end

        % Button pushed function: ResetSubplotConfigurationButton
        function ResetSubplotConfigurationButtonPushed(app, event)
            % Check that user intends to reset form
            message = "Reset subplot configuration to defaults?";
            btn1 = "Yes";
            btn2 = "No";
            choice = questdlg(message, "Warning", btn1, btn2, btn2);
            if choice == "No"
                return;
            end

            reset_subplot_configuration(app);
        end

        % Button pushed function: ResetVideoParametersSeizureDataButton
        function ResetVideoParametersSeizureDataButtonPushed(app, event)
            % Check that user intends to reset form
            message = "Reset video parameters to defaults and clear loaded seizure data?";
            btn1 = "Yes";
            btn2 = "No";
            choice = questdlg(message, "Warning", btn1, btn2, btn2);
            if choice == "No"
                return;
            end
            
            reset_video_parameters(app);
            reset_loaded_seizure_data(app);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            pt = app.PatientIDEditField.Value;
            sz = app.ClipIDEditField.Value;
            ptsz = [pt '_' sz];
            
            % Check if seizure data has already been loaded
            if strcmp(app.currentLoadedClip, ptsz) && ~isempty(app.d)
               return 
            end
            
            datapath = getenv('KLEEN_DATA');
            opsceapath = fullfile(datapath, 'opscea'); %path for parameters sheet
            imagingpath = fullfile(datapath, 'imaging');
            
            ptpath = fullfile(opsceapath, pt); % patient's folder
            szpath = fullfile(ptpath, ptsz); % specific seizure's folder
            ptparams = fullfile(ptpath, 'patient_params.mat');
            % At this point, patient_params.mat should have been generated,
            % so params should be read from the saved file
            if exist(ptparams, 'file') ~= 2
                msgbox(sprintf("patient_params.mat does not exist for patient %s. Please use 'Subplot Configuration' to save out these parameters.", pt));
                return
            end

            clipparams = fullfile(szpath, [ptsz '_params.mat']);
            % clipparams = fullfile(szpath, 'clip_params.mat');
            % Check that seizure exists
            if exist(szpath, 'dir') ~= 7
                msgbox(sprintf("Seizure data with clip ID %s for patient %s doesn't exist. Please specify another clip ID.", sz, pt));
                return;
            end

            loadingbox = uiprogressdlg(app.UIFigure, 'Title', 'Loading seizure data...', 'Indeterminate', 'on');
            load_patient_params(ptparams);
            load_clip_params(clipparams, false, app);
            
            [~, em, ~, ~, ~] = load_prepare_imaging(pt, imagingpath);
            [app.d, app.sfx, app.LL, app.ntp, ~, app.scl, app.ts, app.nns] = load_prepare_clip(pt, szpath, ptsz, app.jumpto, em);
            
            % Update currentLoadedClip to the patient seizure that was just loaded
            app.currentLoadedClip = ptsz;
            
            close(loadingbox);

            app.DataLoadedInfoLabel.Text = sprintf("Seizure data currently loaded for: patient %s with clip ID %s", pt, sz);
        end

        % Button pushed function: PreviewLayoutButton
        function PreviewLayoutButtonPushed(app, event)
            pt = app.PatientIDEditField.Value;
            if isempty(pt)
                msgbox("Please specify a patient ID");
                return;
            end
            
            datapath = getenv('KLEEN_DATA');
            imagingpath = fullfile(datapath, 'imaging');

            loadingbox = uiprogressdlg(app.UIFigure, 'Title', 'Loading subplot preview...', 'Indeterminate', 'on');

            % bgc = app.PreviewLayoutButton.BackgroundColor;
            % tc = app.PreviewLayoutButton.FontColor;
            % app.PreviewLayoutButton.BackgroundColor = [0.8 0.8 0.8];
            % app.PreviewLayoutButton.Text = 'Loading...';
            % app.PreviewLayoutButton.FontColor = [0.5 0.5 0.5];
            
            load_patient_params('', app);

            % This is similar to GenerateVideoPreviewButtonPushed but it 
            % assumes that no data has been loaded. Even if data is
            % loaded, it will not plot any heatmaps because it does not
            % rely on the ClipID being set
            [eleclabels, em, nnsDefault, depthch, axislim] = load_prepare_imaging(pt, imagingpath);
            [ytl, nch, chanorder] = prepare_plotting(eleclabels, nnsDefault, [], true);
            
            % Need to set default (filler) variables for colorbar and other variables temporarily 
            global S;
            S.cax = [-20 20];
            S.cm = cmOPSCEAjet;
            S.gsp = 25;
            S.sliceplane = 'c';
            S.maxbased = true;
              
            figure('color', 'w', 'Position', [1 5 1280 700], 'Name', 'Preview', 'NumberTitle', 'off');
            global tiles;
            tiledlayout(tiles.layout.rows, tiles.layout.cols);
            plot_frame( ...
                0, true, [], [], 0, nch, nnsDefault, 1, [], ...
                ytl, chanorder, true, pt, em, depthch, axislim, datapath, S.maxbased ...
            );

            % app.PreviewLayoutButton.BackgroundColor = bgc;
            % app.PreviewLayoutButton.Text = 'Preview Subplot Layout';
            % app.PreviewLayoutButton.FontColor = tc;

            % Give an extra second before closing the box
            pause('on');
            pause(2);
            pause('off');
            close(loadingbox);
        end

        % Button pushed function: RunOPSCEAButton
        function RunOPSCEAButtonPushed(app, event)
            pt = app.PatientIDEditField.Value;
            sz = app.ClipIDEditField.Value;

            % Save out clip_params
            viddir = fullfile(getenv('KLEEN_DATA'), 'opscea', 'ictal_cinema_library');
            finalClipID = save_clip_params(app, viddir);
            
            if ~isempty(finalClipID)
                message = sprintf("Video params for clip ID %s saved for patient %s", finalClipID, pt);
                msgbox(message);
            end

            %return;
            
            global S;
            S.maxbased=true; %default, less prone to bias from high-density (explained in Chinedu-Eneh et al., 2025)
            disp('Running OPSCEA, please wait...')
            OPSCEA(pt, sz, true, 0, false, S.maxbased);
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            % Check that user intends to reset form
            message = "Reset all values to defaults? This includes the subplot configuration, video parameters, and loaded seizure data.";
            btn1 = "Yes";
            btn2 = "No";
            choice = questdlg(message, "Warning", btn1, btn2, btn2);
            if choice == "No"
                return;
            end
            
            reset_subplot_configuration(app);
            reset_video_parameters(app);
            reset_loaded_seizure_data(app);
        end

        % Button pushed function: LoadTemplateLayoutButton
        function LoadTemplateLayoutButtonPushed(app, event)
            % Reset patient ID
            patientID = app.PatientIDEditField.Value;
            if ~isempty(patientID)
                message = sprintf("Are you sure you want to overwrite current subplot configuration for patient %s?", patientID);
                btn1 = "Yes";
                btn2 = "No";
                choice = questdlg(message, "Warning", btn1, btn2, btn2);
                if choice == "No"
                    return;
                end
            end

            app.PatientIDEditField.Value = "";

            templateDir = "patient_param_templates";

            templateId = app.LoadTemplateLayoutEditField.Value;
            filename = fullfile(getenv("KLEEN_DATA"), 'opscea', templateDir, sprintf('template_%s.mat', templateId));
            if exist(filename, "file") == 2
                patientParams = load(filename);

                fill_subplot_configuration(patientParams, app);
            else
                msgbox(sprintf("Template %s not found. Please select options 1–5.", templateId));
            end
        end

        % Button pushed function: FindSimilarSubjectsButton
        function FindSimilarSubjectsButtonPushed(app, event)
            patientID = app.PatientIDEditField.Value;
            [~, eComponents, ~] = ICEEG_components(patientID, 1);
            if isempty(eComponents)
                msgbox(sprintf("eComponents missing for subject %s", patientID));
                return;
            end

            loadingbox = uiprogressdlg(app.UIFigure, 'Title', 'Searching..', 'Indeterminate', 'on');

            % Get grid electrodes
            gridElecs = eComponents(matches(eComponents(:,2), "grid", 'IgnoreCase', true), :);
            nGridElecs = size(gridElecs, 1);

            % Get depth electrodes
            depths = eComponents(matches(eComponents(:,2), "depth", 'IgnoreCase', true), :);
            nDepths = size(depths, 1);

            % Loop through all existing subjects to find a similar layout
            opsceapath = fullfile(getenv('KLEEN_DATA'), 'opscea'); 
            contents = dir(opsceapath);
            isMatch = [contents.isdir] & startsWith({contents.name}, 'EC');
            subfolders = {contents(isMatch).name};

            candidates = string.empty;
            for i = 1:length(subfolders)
                currentFolder = subfolders{i};
                % Skip this patient
                if strcmp(currentFolder, patientID)
                    continue;
                end
                patientParams = fullfile(opsceapath, currentFolder, 'patient_params.mat');
                if exist(patientParams, 'file') == 2
                    data = load(patientParams);
                    % Check if other patient's configuration is similar to
                    % this patient's
                    if (size(data.depth, 1) == nDepths) & (size(data.surface, 1) >= 1)
                        candidates(end+1) = currentFolder;
                    end
                end
            end

            if isempty(candidates)
                msgbox(sprintf("No similar subjects found. Please use a template."));
            else
                candidatesStr = strjoin(candidates, ', ');
                msgbox(sprintf("The following subjects have a similar number of depth electrodes as %s: %s", patientID, candidatesStr));
            end

            % Give an extra second before closing the box
            pause('on');
            pause(2);
            pause('off');
            close(loadingbox);
        end

        % Button pushed function: RepopulateDepthsButton
        function RepopulateDepthsButtonPushed(app, event)
            % Read current depth electrodes in UI table
            depthElectrodesTable = app.DepthElectrodesTable.Data;
            labels = depthElectrodesTable.labels;
            if size(labels, 1) == 0
                msgbox(sprintf("No depths to repopulate! Please load from a template or use Add Electrode Plot"));
                return
            end
            
            % Get depth electrodes for the specified patient
            patientID = app.PatientIDEditField.Value;
            etype=app.ClipIDConfigTable.Data{"etype", 2}{1};
            global S; S.etype=etype; %update global variable for downstream functions
            if     strcmpi(etype,'clinical'); clin1TDT2=1; 
            elseif strcmpi(etype,'TDT');      clin1TDT2=2; 
            end

            [~, eComponents, ~] = ICEEG_components(patientID, clin1TDT2);
            
            labels = eComponents(matches(eComponents(:,2), "depth", 'IgnoreCase', true), 1);
            depthStarts = string(eComponents(matches(eComponents(:,2), "depth", 'IgnoreCase', true), 3));
            depthEnds = string(eComponents(matches(eComponents(:,2), "depth", 'IgnoreCase', true), 4));
            zipped = strcat(cellstr(depthStarts), ':', cellstr(depthEnds));
            
            % n_labels and n_depths should be equal
            n = min(size(labels, 1), size(depthElectrodesTable.labels, 1));
            depthElectrodesTable.labels(1:n) = labels(1:n);
            depthElectrodesTable.depths(1:n) = zipped(1:n);
            
            % Update table
            app.DepthElectrodesTable.Data = depthElectrodesTable;

            % Give warning if there is a mismatch between n_depths found
            % for this patient and n_depths loaded in the table template
            if size(labels, 1) ~= size(depthElectrodesTable.labels, 1)
                msgbox(sprintf(...
                    "Mismatch between number of depths for patient (%d) " + ...
                    "and depths in template (%d). Updated %d depths.", ...
               size(depthElectrodesTable.labels, 1), size(labels, 1), n));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.VideoParametersPanel = uipanel(app.UIFigure);
            app.VideoParametersPanel.Title = 'Video Parameters';
            app.ClipIDConfigTable = uitable(app.VideoParametersPanel);
            app.ClipIDConfigTable.ColumnName = {'Parameter'; 'Value'};
            app.ClipIDConfigTable.RowName = {};
            app.ClipIDConfigTable.ColumnEditable = [false true];
            app.SearchExistingClipButton = uibutton(app.VideoParametersPanel, 'push');
            app.SearchExistingClipButton.ButtonPushedFcn = createCallbackFcn(app, @SearchExistingClipButtonPushed, true);
            app.SearchExistingClipButton.Text = 'Search Existing Clip';
            app.ClipIDEditFieldLabel = uilabel(app.VideoParametersPanel);
            app.ClipIDEditFieldLabel.Text = 'Clip ID';
            app.ClipIDEditField = uieditfield(app.VideoParametersPanel, 'text');
            app.SaveClipParams = uibutton(app.VideoParametersPanel, 'push');
            app.SaveClipParams.ButtonPushedFcn = createCallbackFcn(app, @SaveClipParamsButtonPushed, true);
            app.SaveClipParams.Text = 'Save Video Parameters';
            app.GenerateVideoPreviewButton = uibutton(app.VideoParametersPanel, 'push');
            app.GenerateVideoPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateVideoPreviewButtonButtonPushed, true);
            app.GenerateVideoPreviewButton.Text = 'Preview';
            app.ResetVideoParametersSeizureDataButton = uibutton(app.VideoParametersPanel, 'push');
            app.ResetVideoParametersSeizureDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetVideoParametersSeizureDataButtonPushed, true);
            app.ResetVideoParametersSeizureDataButton.Text = 'Reset Video Parameters & Seizure Data';
            app.DataLoadedInfoLabel = uilabel(app.VideoParametersPanel);
            app.DataLoadedInfoLabel.Text = 'Seizure data currently loaded for: None';
            app.LoadDataButton = uibutton(app.VideoParametersPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Seizure Data';
            app.JumpToEditField = uieditfield(app.VideoParametersPanel, 'text');
            app.JumpToEditField.Value = '1.0';
            app.JumpToEditFieldLabel = uilabel(app.VideoParametersPanel);
            app.JumpToEditFieldLabel.Text = 'Preview video at timepoint (sec):';
            app.RunOPSCEAButton = uibutton(app.VideoParametersPanel, 'push');
            app.RunOPSCEAButton.ButtonPushedFcn = createCallbackFcn(app, @RunOPSCEAButtonPushed, true);
            app.RunOPSCEAButton.Text = 'Run OPSCEA';
            app.ResetAllButton = uibutton(app.VideoParametersPanel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.SubplotConfigurationPanel = uipanel(app.UIFigure);
            app.SubplotConfigurationPanel.Title = 'Subplot Configuration';
            app.SearchExistingPatientButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.SearchExistingPatientButton.ButtonPushedFcn = createCallbackFcn(app, @SearchExistingPatientButtonPushed, true);
            app.SearchExistingPatientButton.Text = 'Search Existing Patient';
            app.ConfigurePlotElementsTable = uitable(app.SubplotConfigurationPanel);
            app.ConfigurePlotElementsTable.ColumnName = {'Element'; 'Tile'; 'Number of Rows'; 'Number of Columns'};
            app.ConfigurePlotElementsTable.RowName = {};
            app.ConfigurePlotElementsTable.ColumnEditable = [false true true true];
            app.ConfigurePlotElementsLabel = uilabel(app.SubplotConfigurationPanel);
            app.ConfigurePlotElementsLabel.Text = 'Configure Plot Elements';
            app.SurfaceElectrodesTable = uitable(app.SubplotConfigurationPanel);
            app.SurfaceElectrodesTable.ColumnName = {'Tile'; '# Rows'; '# Columns'; 'Zoom'; 'Surfaces'; 'Opacity'; 'View'; 'ShowSlices'; 'Remove'};
            app.SurfaceElectrodesTable.RowName = {};
            app.SurfaceElectrodesTable.ColumnEditable = true;
            app.SurfaceElectrodesLabel = uilabel(app.SubplotConfigurationPanel);
            app.SurfaceElectrodesLabel.Text = 'Surface Electrodes';
            app.DepthElectrodesTable = uitable(app.SubplotConfigurationPanel);
            app.DepthElectrodesTable.ColumnName = {'Tile'; '# Rows'; '# Columns'; 'Zoom'; 'Labels'; 'Color'; 'Depths'; 'Remove Suplot'};
            app.DepthElectrodesTable.RowName = {};
            app.DepthElectrodesTable.ColumnEditable = true;
            app.DepthElectrodesLabel = uilabel(app.SubplotConfigurationPanel);
            app.DepthElectrodesLabel.Text = 'Depth Electrodes';
            app.AddSurfaceElectrodePlotButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.AddSurfaceElectrodePlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddSurfaceElectrodePlotButtonPushed, true);
            app.AddSurfaceElectrodePlotButton.Text = 'Add Electrode Plot';
            app.AddDepthElectrodePlotButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.AddDepthElectrodePlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddDepthElectrodePlotButtonPushed, true);
            app.AddDepthElectrodePlotButton.Text = 'Add Electrode Plot';
            app.UpdateSurfaceElectrodesButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.UpdateSurfaceElectrodesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSurfaceElectrodesButtonPushed, true);
            app.UpdateSurfaceElectrodesButton.Text = 'Update Config';
            app.UpdateDepthElectrodesButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.UpdateDepthElectrodesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDepthElectrodesButtonPushed, true);
            app.UpdateDepthElectrodesButton.Text = 'Update Config';
            app.SaveSubplotConfigurationButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.SaveSubplotConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSubplotConfigurationButtonPushed, true);
            app.SaveSubplotConfigurationButton.Text = 'Save Subplot Configuration';
            app.ConfigurePlotLayoutLabel = uilabel(app.SubplotConfigurationPanel);
            app.ConfigurePlotLayoutLabel.Text = 'Plot Layout';
            app.ConfigurePlotLayoutEditField = uieditfield(app.SubplotConfigurationPanel, 'text');
            app.ConfigurePlotLayoutEditField.Placeholder = 'rows,columns';
            app.ResetSubplotConfigurationButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.ResetSubplotConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSubplotConfigurationButtonPushed, true);
            app.ResetSubplotConfigurationButton.Text = 'Reset Subplot Configuration';
            app.PreviewLayoutButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.PreviewLayoutButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewLayoutButtonPushed, true);
            app.PreviewLayoutButton.Text = 'Preview Subplot Layout';
            app.PatientIDEditFieldLabel = uilabel(app.SubplotConfigurationPanel);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.SubplotConfigurationPanel, 'text');
            app.PatientIDEditField.Placeholder = 'EC000';
            app.LoadTemplateLayoutLabel = uilabel(app.SubplotConfigurationPanel);
            app.LoadTemplateLayoutLabel.Text = 'Template';
            app.LoadTemplateLayoutEditField = uieditfield(app.SubplotConfigurationPanel, 'text');
            app.LoadTemplateLayoutEditField.Placeholder = '1-5';
            app.LoadTemplateLayoutButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.LoadTemplateLayoutButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTemplateLayoutButtonPushed, true);
            app.LoadTemplateLayoutButton.Text = 'Load Template Layout';
            app.FindSimilarSubjectsButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.FindSimilarSubjectsButton.ButtonPushedFcn = createCallbackFcn(app, @FindSimilarSubjectsButtonPushed, true);
            app.FindSimilarSubjectsButton.Text = 'Find Similar Subjects';
            app.RepopulateDepthsButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.RepopulateDepthsButton.ButtonPushedFcn = createCallbackFcn(app, @RepopulateDepthsButtonPushed, true);
            app.RepopulateDepthsButton.Text = 'Repopulate Depths';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_draft_updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end